classdef GUI_PAF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EnddistancectrlfreEditField    matlab.ui.control.NumericEditField
        EnddistancectrlfreEditFieldLabel  matlab.ui.control.Label
        StartdistancectrlfreEditField  matlab.ui.control.NumericEditField
        StartdistancectrlfreEditFieldLabel  matlab.ui.control.Label
        EnddistancectrlEditField       matlab.ui.control.NumericEditField
        EnddistancectrlEditFieldLabel  matlab.ui.control.Label
        StartdistancectrlEditField     matlab.ui.control.NumericEditField
        StartdistancectrlEditFieldLabel  matlab.ui.control.Label
        FixframeCheckBox               matlab.ui.control.CheckBox
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        MaxFramePAEditField            matlab.ui.control.NumericEditField
        MaxFramePAEditFieldLabel       matlab.ui.control.Label
        MaxFrameCtrlEditField          matlab.ui.control.NumericEditField
        MaxFrameCtrlEditFieldLabel     matlab.ui.control.Label
        PAFsignalEditField             matlab.ui.control.NumericEditField
        PAFsignalEditFieldLabel        matlab.ui.control.Label
        FFTEditField                   matlab.ui.control.NumericEditField
        FFTEditFieldLabel              matlab.ui.control.Label
        PAsignalEditField              matlab.ui.control.NumericEditField
        PAsignalEditFieldLabel         matlab.ui.control.Label
        ExpButton                      matlab.ui.control.Button
        CtrlButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        DatapathEditField              matlab.ui.control.EditField
        DatapathEditFieldLabel         matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
        LogTextAreaLabel               matlab.ui.control.Label
        AverageCheckBox                matlab.ui.control.CheckBox
        BandpassCheckBox               matlab.ui.control.CheckBox
        HighfrequencyEditField         matlab.ui.control.NumericEditField
        HighfrequencyEditFieldLabel    matlab.ui.control.Label
        LowfrequencyEditField          matlab.ui.control.NumericEditField
        LowfrequencyEditFieldLabel     matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        EnddistanceEditField           matlab.ui.control.NumericEditField
        EnddistanceEditFieldLabel      matlab.ui.control.Label
        StartdistanceEditField         matlab.ui.control.NumericEditField
        StartdistanceEditFieldLabel    matlab.ui.control.Label
        NumAvgEditField                matlab.ui.control.NumericEditField
        NumAvgEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        fs
        dt
        sizePixel
        t
        x
        Datapath
        sig_ctrl
        sig_PA
        sig_ctrl_avg
        sig_PA_avg
        FFN_ctrl
        FFN_PA
        FN_ctrl
        FN_PA
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
        end

        % Callback function
        function NumAvgEditFieldValueChanged(app, event)
            value = app.NumAvgEditField.Value;
            
        end

        % Value changed function: NumAvgEditField
        function NumAvgEditFieldValueChanged2(app, event)
            value = app.NumAvgEditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.LogTextArea.Value = 'Start';
            pause(0.1)
            fs = 50;
            dt = 1/fs;
            NumSample = 40960;
            t = (0:dt:(NumSample/2-1)*dt)';
            x = t*1.5-300;

            if app.AverageCheckBox.Value == 1
                app.LogTextArea.Value = "Averaging start";
                pause(0.1)
                sig_ctrl_avg = pfAvg(app.sig_ctrl,app.NumAvgEditField.Value);
                sig_PA_avg = pfAvg(app.sig_PA,app.NumAvgEditField.Value);
                app.LogTextArea.Value = "Averaging end";
                pause(0.1)
            end

            if app.BandpassCheckBox.Value == 1
                app.LogTextArea.Value = "Bandpassing start";
                pause(0.1)
                sig_ctrl_bandpass = pfBp(sig_ctrl_avg,app.LowfrequencyEditField.Value,app.HighfrequencyEditField.Value,fs);
                sig_PA_bandpass = pfBp(sig_PA_avg,app.LowfrequencyEditField.Value,app.HighfrequencyEditField.Value,fs);
                app.LogTextArea.Value = "Bandpassing end";
                pause(0.1)
            end


            temp_min = app.StartdistanceEditField.Value;
            temp_max = app.EnddistanceEditField.Value;
            xlim_min = find(x<temp_min,1,'last');
            xlim_max = find(x<temp_max,1,'last');

            temp_min2 = app.StartdistancectrlEditField.Value;
            temp_max2 = app.EnddistancectrlEditField.Value;
            xlim_min2 = find(x<temp_min2,1,'last');
            xlim_max2 = find(x<temp_max2,1,'last');

            temp_min3 = app.StartdistancectrlfreEditField.Value;
            temp_max3 = app.EnddistancectrlfreEditField.Value;
            xlim_min3 = find(x<temp_min3,1,'last');
            xlim_max3 = find(x<temp_max3,1,'last');

            temp1 = abs(hilbert(sig_PA_bandpass(xlim_min:xlim_max,:)));
            [PAsig_1 idx_1]= max(temp1,[],2);
            [PAsig_max idx_max]= max(PAsig_1);

            temp2 = abs(hilbert(sig_ctrl_bandpass(xlim_min2:xlim_max2,:)));
            RowSize=size(temp2,1);
            temp2(temp2 == 0) = [];
            temp2 = reshape(temp2,RowSize,[]);

            [PAsig_min idx_min]= max(mean(temp2));
%             [PAsig_min idx_min]= min(mean(temp2));
            PAsig_min = max(temp2(:,idx_min));


            if app.FixframeCheckBox.Value == 0
                MaxFrame = idx_1(idx_max);
                MinFrame = idx_min;
                app.MaxFrameCtrlEditField.Value = MinFrame + app.FFN_ctrl-1;
                app.MaxFramePAEditField.Value = MaxFrame + app.FFN_PA-1;
            else
                MaxFrame = app.MaxFramePAEditField.Value-app.FFN_PA+1;
                [PAsig_max, idx_max] = max(temp1(:,MaxFrame));
                MinFrame = app.MaxFrameCtrlEditField.Value-app.FFN_ctrl+1;
                PAsig_min = max(temp2(:,MinFrame));
            end

            f1 = figure(1); f1.WindowState = 'maximized';
            subplot(2,3, [1 2]);p2 = plot(x,sig_ctrl_bandpass(:,MinFrame));
            p2.Color = 'k';
            p2.LineWidth = 4;
            hold on
            p3 = plot(x,sig_PA_bandpass(:,MaxFrame));
            p3.Color = 'r';
            p3.LineWidth = 4;
            hold off
            
            xlabel('Distance [mm]');
            ylabel('PA amplitude [a.u]');
            
            
            set(gca, 'FontSize',15);
            set(gca,'LineWidth',4);
            set(gca,'FontWeight','bold');
            grid on
            line([temp_min temp_min],ylim,'Color','m','LineStyle','--','LineWidth',4)
            line([temp_max temp_max],ylim,'Color','m','LineStyle','--','LineWidth',4)
            line([temp_min2 temp_min2],ylim,'Color','b','LineStyle','--','LineWidth',4)
            line([temp_max2 temp_max2],ylim,'Color','b','LineStyle','--','LineWidth',4)
            xlim([3 8]); ylim([-0.2 0.2]);
            title("PA signal")
            box off
            
            
            subplot(2,3, [4 5]); 
            p4 = plot(x,abs(hilbert(sig_PA_bandpass(:,MaxFrame))));
            p4.Color = 'r';
            p4.LineWidth = 4;
            hold on
            line([x(xlim_min+idx_max-1) x(xlim_min+idx_max-1)],ylim,'Color','m','LineStyle','--','LineWidth',4)
            line(xlim,[PAsig_min PAsig_min],'Color','k','LineStyle','--','LineWidth',4)
            
            xlim([3 8]); ylim([0 0.5]);
            PAsig_idx= (PAsig_max - PAsig_min)*1000;
            app.PAsignalEditField.Value = PAsig_idx;
            title(sprintf("PAsig diff amplitude = %4.0f",PAsig_idx));
            set(gca, 'FontSize',15);
            set(gca,'LineWidth',4);
            set(gca,'FontWeight','bold');
            grid on
            box off
            hold off
            
            s = app.LowfrequencyEditField.Value;
            e = app.HighfrequencyEditField.Value;

            subplot(2,3,[3 6]);
            L = xlim_max - xlim_min;
            L3 = xlim_max3 - xlim_min3;
            f = fs*(0:(L/2))/L;
            f3 = fs*(0:(L3/2))/L3;
            df = f(2)-f(1);
            df3 = f3(2)-f3(1);
            f_s_idx = find(f<s,1,'last');
            f_e_idx = find(f<e,1,'last');
            f_s_idx3 = find(f3<s,1,'last');
            f_e_idx3 = find(f3<e,1,'last');

            sig_PA_fft = fft(sig_PA_avg(xlim_min:xlim_max,MaxFrame));
            P2 = abs(sig_PA_fft/L); %양방향
            P1 = P2(1:fix(L/2)+1); %단방향
            P1(2:end-1) = 2*P1(2:end-1); %단방향 크기 2배
            P1_nor_PA = P1/max(P1); %Normalization
            P1_nor_PA_mean = mean(P1_nor_PA(f_s_idx:f_e_idx));
            P1_nor_PA_area = sum(P1_nor_PA(f_s_idx:f_e_idx).*df);
            plot(f,20*log10(P1_nor_PA),'r','LineWidth',4);xlim([0 25])
            hold on
            line(xlim,[20*log10(P1_nor_PA_mean) 20*log10(P1_nor_PA_mean)],'Color','m','LineStyle','--','LineWidth',3)
            
            sig_ctrl_fft = fft(sig_ctrl_avg(xlim_min3:xlim_max3,MinFrame));
            P2 = abs(sig_ctrl_fft/L3); %양방향
            P1 = P2(1:fix(L3/2)+1); %단방향
            P1(2:end-1) = 2*P1(2:end-1); %단방향 크기 2배
            P1_nor_ctrl = P1/max(P1); %Normalization
            P1_nor_ctrl_mean = mean(P1_nor_ctrl(f_s_idx3:f_e_idx3));
            P1_nor_ctrl_area = sum(P1_nor_ctrl(f_s_idx3:f_e_idx3).*df3);
            plot(f3,20*log10(P1_nor_ctrl),'k','LineWidth',4)
            xlim([0 25]); ylim([-60 0]);
            line(xlim,[20*log10(P1_nor_ctrl_mean) 20*log10(P1_nor_ctrl_mean)],'Color','blue','LineStyle','--','LineWidth',3)
            line([s s],ylim,'Color','green','LineStyle','--','LineWidth',3)
            line([e e],ylim,'Color','green','LineStyle','--','LineWidth',3)
            
            
            Frequency_idx = (20*log10(P1_nor_PA_mean) - 20*log10(P1_nor_ctrl_mean));
            app.FFTEditField.Value = Frequency_idx;

            % Frequency_idx = (P1_nor_PA_area -P1_nor_ctrl_area);
            Overall_idx = (PAsig_idx + Frequency_idx)/2;
            app.PAFsignalEditField.Value = Overall_idx;

            title(sprintf("Frequency idx = %4.2f\n Overall index = %4.0f" ...
                ,Frequency_idx,Overall_idx));
            
            hold off
            xlabel('Frequency [MHz]');
            ylabel('Amplitude [a.u]');
            
            set(gca, 'FontSize',15);
            set(gca,'LineWidth',4);
            set(gca,'FontWeight','bold');
            grid on
            box off
            xlim([0 25])
            app.LogTextArea.Value = "Saving";
            pause(0.1)
            save_path = app.DatapathEditField.Value+"\log";
            Fname = app.FN_PA;
            FolderName = app.FN_ctrl;
            mkdir(save_path+"\"+Fname+"\"+FolderName);
            Fname2 = "A(" + num2str(app.NumAvgEditField.Value) + ")_" ...
                + "D(" + num2str(app.StartdistanceEditField.Value) + "-" + num2str(app.EnddistanceEditField.Value) + ")_"...
                + "B(" + num2str(app.LowfrequencyEditField.Value) + "-" + num2str(app.HighfrequencyEditField.Value)+")";

           
            fileID = fopen(save_path+"\"+Fname+"\"+FolderName+"\"+Fname2+".txt",'w');
            fprintf(fileID,'%s | %f\n','PA max Frame',app.MaxFramePAEditField.Value);
            fprintf(fileID,'%s | %f\n','Ctrl max Frame',app.MaxFrameCtrlEditField.Value);
            fprintf(fileID,'%s | %f\n','PA signal',app.PAFsignalEditField.Value);
            fprintf(fileID,'%s | %f\n','FFT signal',app.FFTEditField.Value);
            fprintf(fileID,'%s | %f\n','PAF signal',app.PAFsignalEditField.Value);
            fprintf(fileID,'%s | %s\n','Ctrl FN',app.EditField.Value);
            fprintf(fileID,'%s | %s\n','PA FN',app.EditField_2.Value);
            fclose(fileID);
            app.LogTextArea.Value = "End";


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.DatapathEditField.Value = uigetdir('C:\Users\moong\OneDrive\3.Project\2.PAF\1.ExperimentData',...
            'Select Data folder');
            figure(app.UIFigure);
        end

        % Button pushed function: CtrlButton
        function CtrlButtonPushed(app, event)
            app.LogTextArea.Value = "Loading ctrl start";
            [app.sig_ctrl, app.FFN_ctrl, app.FN_ctrl]= PAFData(app.DatapathEditField.Value);
            app.EditField.Value = app.FN_ctrl;
            figure(app.UIFigure);
            app.LogTextArea.Value = "Loading ctrl end";
        end

        % Button pushed function: ExpButton
        function ExpButtonPushed(app, event)
            app.LogTextArea.Value = "Loading PA start";
            [app.sig_PA, app.FFN_PA, app.FN_PA] = PAFData(app.DatapathEditField.Value);
            app.EditField_2.Value = app.FN_PA;
            figure(app.UIFigure);
            app.LogTextArea.Value = "Loading PA end";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumAvgEditFieldLabel = uilabel(app.UIFigure);
            app.NumAvgEditFieldLabel.Text = 'Num Avg';
            app.NumAvgEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumAvgEditField.ValueChangedFcn = createCallbackFcn(app, @NumAvgEditFieldValueChanged2, true);
            app.NumAvgEditField.Value = 5;
            app.StartdistanceEditFieldLabel = uilabel(app.UIFigure);
            app.StartdistanceEditFieldLabel.Text = 'Start distance';
            app.StartdistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartdistanceEditField.Value = 4;
            app.EnddistanceEditFieldLabel = uilabel(app.UIFigure);
            app.EnddistanceEditFieldLabel.Text = 'End distance';
            app.EnddistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnddistanceEditField.Value = 8;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LowfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.LowfrequencyEditFieldLabel.Text = 'Low frequency';
            app.LowfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowfrequencyEditField.Value = 10;
            app.HighfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.HighfrequencyEditFieldLabel.Text = 'High frequency';
            app.HighfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighfrequencyEditField.Value = 22.5;
            app.BandpassCheckBox = uicheckbox(app.UIFigure);
            app.BandpassCheckBox.Text = 'Bandpass';
            app.BandpassCheckBox.Value = true;
            app.AverageCheckBox = uicheckbox(app.UIFigure);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.DatapathEditFieldLabel = uilabel(app.UIFigure);
            app.DatapathEditFieldLabel.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CtrlButton = uibutton(app.UIFigure, 'push');
            app.CtrlButton.ButtonPushedFcn = createCallbackFcn(app, @CtrlButtonPushed, true);
            app.CtrlButton.Text = 'Ctrl';
            app.ExpButton = uibutton(app.UIFigure, 'push');
            app.ExpButton.ButtonPushedFcn = createCallbackFcn(app, @ExpButtonPushed, true);
            app.ExpButton.Text = 'Exp';
            app.PAsignalEditFieldLabel = uilabel(app.UIFigure);
            app.PAsignalEditFieldLabel.Text = 'PA signal';
            app.PAsignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.FFTEditFieldLabel = uilabel(app.UIFigure);
            app.FFTEditFieldLabel.Text = 'FFT';
            app.FFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PAFsignalEditFieldLabel = uilabel(app.UIFigure);
            app.PAFsignalEditFieldLabel.Text = 'PAF signal';
            app.PAFsignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFrameCtrlEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFrameCtrlEditFieldLabel.Text = 'Max Frame (Ctrl)';
            app.MaxFrameCtrlEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFramePAEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFramePAEditFieldLabel.Text = 'Max Frame (PA)';
            app.MaxFramePAEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.FixframeCheckBox = uicheckbox(app.UIFigure);
            app.FixframeCheckBox.Text = 'Fix frame';
            app.StartdistancectrlEditFieldLabel = uilabel(app.UIFigure);
            app.StartdistancectrlEditFieldLabel.Text = 'Start distance(ctrl)';
            app.StartdistancectrlEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartdistancectrlEditField.Value = 4;
            app.EnddistancectrlEditFieldLabel = uilabel(app.UIFigure);
            app.EnddistancectrlEditFieldLabel.Text = 'End distance (ctrl)';
            app.EnddistancectrlEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnddistancectrlEditField.Value = 7;
            app.StartdistancectrlfreEditFieldLabel = uilabel(app.UIFigure);
            app.StartdistancectrlfreEditFieldLabel.Text = 'Start distance(ctrl-fre)';
            app.StartdistancectrlfreEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnddistancectrlfreEditFieldLabel = uilabel(app.UIFigure);
            app.EnddistancectrlfreEditFieldLabel.Text = 'End distance(ctrl-fre)';
            app.EnddistancectrlfreEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PAF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end